classdef piano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        MotionCaptureXEditField_5       matlab.ui.control.NumericEditField
        MotionCaptureXEditFieldLabel_5  matlab.ui.control.Label
        MotionCaptureXEditField_4       matlab.ui.control.NumericEditField
        MotionCaptureXEditFieldLabel_4  matlab.ui.control.Label
        MotionCapturemarkerYcordEditField_2  matlab.ui.control.NumericEditField
        MotionCapturemarkerYtolernaceLabel  matlab.ui.control.Label
        SynchronizeButton               matlab.ui.control.Button
        MotionCaptureXEditField_3       matlab.ui.control.NumericEditField
        MotionCaptureXEditFieldLabel_3  matlab.ui.control.Label
        MotionCaptureXEditField_2       matlab.ui.control.NumericEditField
        MotionCaptureXEditFieldLabel_2  matlab.ui.control.Label
        Label6                          matlab.ui.control.Label
        Label5                          matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MotionCaptureButton             matlab.ui.control.Button
        sEMGsensorButton                matlab.ui.control.Button
        MIDIcaptureButton               matlab.ui.control.Button
        DataAcquisitionUnitButton       matlab.ui.control.Button
        BloodOxygentionSensorButton     matlab.ui.control.Button
        SynchronisationSignalpianoButton  matlab.ui.control.Button
        MotionCapturemarkerYcordEditField  matlab.ui.control.NumericEditField
        MotionCapturemarkerYcordLabel   matlab.ui.control.Label
        renderTab                       matlab.ui.container.Tab
        SETTINGSButtonGroup             matlab.ui.container.ButtonGroup
        GridCheckBox                    matlab.ui.control.CheckBox
        DARKMODECheckBox                matlab.ui.control.CheckBox
        xEditField_5                    matlab.ui.control.NumericEditField
        yLabel                          matlab.ui.control.Label
        xEditField_4                    matlab.ui.control.NumericEditField
        xEditField_3                    matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        xEditField_2                    matlab.ui.control.NumericEditField
        PLOTLabel                       matlab.ui.control.Label
        PianoCheckBox_3                 matlab.ui.control.CheckBox
        BloodOxygentionCheckBox_3       matlab.ui.control.CheckBox
        DAUCheckBox_3                   matlab.ui.control.CheckBox
        MIDICheckBox_3                  matlab.ui.control.CheckBox
        sEMGCheckBox_3                  matlab.ui.control.CheckBox
        MotionCaptureCheckBox_3         matlab.ui.control.CheckBox
        AFTERSYNCLabel                  matlab.ui.control.Label
        BEFORESYNCLabel                 matlab.ui.control.Label
        MotionCaptureCheckBox           matlab.ui.control.CheckBox
        sEMGCheckBox                    matlab.ui.control.CheckBox
        MIDICheckBox                    matlab.ui.control.CheckBox
        DAUCheckBox                     matlab.ui.control.CheckBox
        BloodOxygentionCheckBox         matlab.ui.control.CheckBox
        PianoCheckBox                   matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %FILES
        SynchronisationSignalFile 
        BloodOxygentionSensorFile
        DataAcquisitionUnitFile 
        MIDIcaptureFile
        sEMGsensorFile 
        MotionCaptureFile
    end    

    properties (Access = private)
        %MARKER DATA
        markerLocation = [0, 0, 0] 
        markerTolrance = [0, 0, 0] 
    end

    properties(Access = public)
                %ploting
                nonSyncPlots 
                syncPlots 
    end

    properties(Access = public)
        debugData1 = [1:1:100]
        debugData2 = [100:1:1]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SynchronisationSignalpianoButton
        function SynchronisationSignalpianoButtonPushed(app, event)
            app.SynchronisationSignalFile = uigetfile;
            app.Label.Text = app.SynchronisationSignalFile;
        end

        % Button pushed function: BloodOxygentionSensorButton
        function BloodOxygentionSensorButtonPushed(app, event)
            app.BloodOxygentionSensorFile = uigetfile;
            app.Label2.Text = app.BloodOxygentionSensorFile;
        end

        % Button pushed function: DataAcquisitionUnitButton
        function DataAcquisitionUnitButtonPushed(app, event)
             app.DataAcquisitionUnitFile = uigetfile;
            app.Label3.Text = app.DataAcquisitionUnitFile;
        end

        % Button pushed function: MIDIcaptureButton
        function MIDIcaptureButtonPushed(app, event)
             app.MIDIcaptureFile = uigetfile;
            app.Label4.Text = app.MIDIcaptureFile;
        end

        % Button pushed function: sEMGsensorButton
        function sEMGsensorButtonPushed(app, event)
             app.sEMGsensorFile = uigetfile;
            app.Label5.Text = app.sEMGsensorFile;
        end

        % Button pushed function: MotionCaptureButton
        function MotionCaptureButtonPushed(app, event)
             app.MotionCaptureFile = uigetfile;
            app.Label6.Text = app.MotionCaptureFile;
        end

        % Value changed function: MotionCaptureXEditField_2
        function MotionCaptureXEditField_2ValueChanged(app, event)
            value = app.MotionCaptureXEditField_2.Value;
            app.markerLocation(1) = value;
        end

        % Value changed function: MotionCapturemarkerYcordEditField
        function MotionCapturemarkerYcordEditFieldValueChanged(app, event)
            value = app.MotionCapturemarkerYcordEditField.Value;
            app.markerLocation(2) = value;
        end

        % Value changed function: MotionCaptureXEditField_3
        function MotionCaptureXEditField_3ValueChanged(app, event)
            value = app.MotionCaptureXEditField_3.Value;
            app.markerLocation(3) = value;
        end

        % Value changed function: MotionCaptureXEditField_4
        function MotionCaptureXEditField_4ValueChanged(app, event)
            value = app.MotionCaptureXEditField_4.Value;
            app.markerTolrance(1) = value;
        end

        % Value changed function: MotionCapturemarkerYcordEditField_2
        function MotionCapturemarkerYcordEditField_2ValueChanged(app, event)
            value = app.MotionCapturemarkerYcordEditField_2.Value;
            app.markerTolrance(2) = value;
        end

        % Value changed function: MotionCaptureXEditField_5
        function MotionCaptureXEditField_5ValueChanged(app, event)
            value = app.MotionCaptureXEditField_5.Value;
            app.markerTolrance(3) = value;
        end

        % Selection changed function: SETTINGSButtonGroup
        function SETTINGSButtonGroupSelectionChanged(app, event)

        end

        % Button down function: SETTINGSButtonGroup
        function SETTINGSButtonGroupButtonDown(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            disp(app.debugData1)
            plot(app.UIAxes, app.debugData1)
        end

        % Value changed function: PianoCheckBox
        function PianoCheckBoxValueChanged(app, event)
            value = app.PianoCheckBox.Value;
            disp(app.syncPlots)
            disp(app.nonSyncPlots)

        end

        % Button down function: renderTab
        function renderTabButtonDown(app, event)
                setOfNonSyncName = {app.PianoCheckBox.Text, app.BloodOxygentionCheckBox.Text, app.DAUCheckBox.Text, app.MIDICheckBox.Text, app.sEMGCheckBox.Text, app.MotionCaptureCheckBox.Text};
                setOfSyncName = {app.PianoCheckBox_3.Text, app.BloodOxygentionCheckBox_3.Text, app.DAUCheckBox_3.Text, app.MIDICheckBox_3.Text, app.sEMGCheckBox_3.Text, app.MotionCaptureCheckBox_3.Text};
                
                app.nonSyncPlots = configureDictionary("string", "logical");
                app.syncPlots = configureDictionary("string", "logical");
                
                for i = setOfNonSyncName
                    if ~(app.nonSyncPlots.isKey(i))
                        app.nonSyncPlots.insert(i, false)
                    end
                end

                for i = setOfSyncName
                    if ~(app.syncPlots.isKey(i))
                        app.syncPlots.insert(i, false)
                    end
                end
        end

        % Value changed function: BloodOxygentionCheckBox
        function BloodOxygentionCheckBoxValueChanged(app, event)
            value = app.BloodOxygentionCheckBox.Value;
            
        end

        % Value changed function: DAUCheckBox
        function DAUCheckBoxValueChanged(app, event)
            value = app.DAUCheckBox.Value;
            
        end

        % Value changed function: MIDICheckBox
        function MIDICheckBoxValueChanged(app, event)
            value = app.MIDICheckBox.Value;
            
        end

        % Value changed function: sEMGCheckBox
        function sEMGCheckBoxValueChanged(app, event)
            value = app.sEMGCheckBox.Value;
            
        end

        % Value changed function: MotionCaptureCheckBox
        function MotionCaptureCheckBoxValueChanged(app, event)
            value = app.MotionCaptureCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout = uigridlayout(app.Tab);
            app.MotionCapturemarkerYcordLabel = uilabel(app.GridLayout);
            app.MotionCapturemarkerYcordLabel.Text = {'Motion Capture:'; ' marker Y cord'};
            app.MotionCapturemarkerYcordEditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapturemarkerYcordEditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapturemarkerYcordEditFieldValueChanged, true);
            app.SynchronisationSignalpianoButton = uibutton(app.GridLayout, 'push');
            app.SynchronisationSignalpianoButton.ButtonPushedFcn = createCallbackFcn(app, @SynchronisationSignalpianoButtonPushed, true);
            app.SynchronisationSignalpianoButton.Text = {'Synchronisation '; 'Signal (piano)'};
            app.BloodOxygentionSensorButton = uibutton(app.GridLayout, 'push');
            app.BloodOxygentionSensorButton.ButtonPushedFcn = createCallbackFcn(app, @BloodOxygentionSensorButtonPushed, true);
            app.BloodOxygentionSensorButton.Text = {'Blood Oxygention'; 'Sensor'};
            app.DataAcquisitionUnitButton = uibutton(app.GridLayout, 'push');
            app.DataAcquisitionUnitButton.ButtonPushedFcn = createCallbackFcn(app, @DataAcquisitionUnitButtonPushed, true);
            app.DataAcquisitionUnitButton.Text = {'Data Acquisition'; 'Unit'};
            app.MIDIcaptureButton = uibutton(app.GridLayout, 'push');
            app.MIDIcaptureButton.ButtonPushedFcn = createCallbackFcn(app, @MIDIcaptureButtonPushed, true);
            app.MIDIcaptureButton.Text = 'MIDI capture';
            app.sEMGsensorButton = uibutton(app.GridLayout, 'push');
            app.sEMGsensorButton.ButtonPushedFcn = createCallbackFcn(app, @sEMGsensorButtonPushed, true);
            app.sEMGsensorButton.Text = 'sEMG sensor';
            app.MotionCaptureButton = uibutton(app.GridLayout, 'push');
            app.MotionCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @MotionCaptureButtonPushed, true);
            app.MotionCaptureButton.Text = 'Motion Capture';
            app.Label = uilabel(app.GridLayout);
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.GridLayout);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.GridLayout);
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.GridLayout);
            app.Label5.Text = 'Label5';
            app.Label6 = uilabel(app.GridLayout);
            app.Label6.Text = 'Label6';
            app.MotionCaptureXEditFieldLabel_2 = uilabel(app.GridLayout);
            app.MotionCaptureXEditFieldLabel_2.Text = {'Motion Capture: '; 'marker X cord'};
            app.MotionCaptureXEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.MotionCaptureXEditField_2.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureXEditField_2ValueChanged, true);
            app.MotionCaptureXEditFieldLabel_3 = uilabel(app.GridLayout);
            app.MotionCaptureXEditFieldLabel_3.Text = {'Motion Capture: '; 'marker Z cord'};
            app.MotionCaptureXEditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.MotionCaptureXEditField_3.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureXEditField_3ValueChanged, true);
            app.SynchronizeButton = uibutton(app.GridLayout, 'push');
            app.SynchronizeButton.Text = 'Synchronize';
            app.MotionCapturemarkerYtolernaceLabel = uilabel(app.GridLayout);
            app.MotionCapturemarkerYtolernaceLabel.Text = {'Motion Capture:'; ' marker Y tolernace'};
            app.MotionCapturemarkerYcordEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapturemarkerYcordEditField_2.ValueChangedFcn = createCallbackFcn(app, @MotionCapturemarkerYcordEditField_2ValueChanged, true);
            app.MotionCaptureXEditFieldLabel_4 = uilabel(app.GridLayout);
            app.MotionCaptureXEditFieldLabel_4.Text = {'Motion Capture: '; 'marker X tolernace'};
            app.MotionCaptureXEditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.MotionCaptureXEditField_4.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureXEditField_4ValueChanged, true);
            app.MotionCaptureXEditFieldLabel_5 = uilabel(app.GridLayout);
            app.MotionCaptureXEditFieldLabel_5.Text = {'Motion Capture: '; 'marker Z tolernace'};
            app.MotionCaptureXEditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.MotionCaptureXEditField_5.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureXEditField_5ValueChanged, true);
            app.renderTab = uitab(app.TabGroup);
            app.renderTab.Title = 'render';
            app.renderTab.ButtonDownFcn = createCallbackFcn(app, @renderTabButtonDown, true);
            app.UIAxes = uiaxes(app.renderTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SETTINGSButtonGroup = uibuttongroup(app.renderTab);
            app.SETTINGSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SETTINGSButtonGroupSelectionChanged, true);
            app.SETTINGSButtonGroup.Title = 'SETTINGS';
            app.SETTINGSButtonGroup.ButtonDownFcn = createCallbackFcn(app, @SETTINGSButtonGroupButtonDown, true);
            app.PianoCheckBox = uicheckbox(app.SETTINGSButtonGroup);
            app.PianoCheckBox.ValueChangedFcn = createCallbackFcn(app, @PianoCheckBoxValueChanged, true);
            app.PianoCheckBox.Text = 'Piano';
            app.BloodOxygentionCheckBox = uicheckbox(app.SETTINGSButtonGroup);
            app.BloodOxygentionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BloodOxygentionCheckBoxValueChanged, true);
            app.BloodOxygentionCheckBox.Text = 'Blood Oxygention';
            app.DAUCheckBox = uicheckbox(app.SETTINGSButtonGroup);
            app.DAUCheckBox.ValueChangedFcn = createCallbackFcn(app, @DAUCheckBoxValueChanged, true);
            app.DAUCheckBox.Text = 'DAU';
            app.MIDICheckBox = uicheckbox(app.SETTINGSButtonGroup);
            app.MIDICheckBox.ValueChangedFcn = createCallbackFcn(app, @MIDICheckBoxValueChanged, true);
            app.MIDICheckBox.Text = 'MIDI';
            app.sEMGCheckBox = uicheckbox(app.SETTINGSButtonGroup);
            app.sEMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @sEMGCheckBoxValueChanged, true);
            app.sEMGCheckBox.Text = 'sEMG ';
            app.MotionCaptureCheckBox = uicheckbox(app.SETTINGSButtonGroup);
            app.MotionCaptureCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureCheckBoxValueChanged, true);
            app.MotionCaptureCheckBox.Text = 'Motion Capture';
            app.BEFORESYNCLabel = uilabel(app.SETTINGSButtonGroup);
            app.BEFORESYNCLabel.Text = 'BEFORE SYNC';
            app.AFTERSYNCLabel = uilabel(app.SETTINGSButtonGroup);
            app.AFTERSYNCLabel.Text = 'AFTER SYNC';
            app.MotionCaptureCheckBox_3 = uicheckbox(app.SETTINGSButtonGroup);
            app.MotionCaptureCheckBox_3.Text = 'Motion Capture';
            app.sEMGCheckBox_3 = uicheckbox(app.SETTINGSButtonGroup);
            app.sEMGCheckBox_3.Text = 'sEMG ';
            app.MIDICheckBox_3 = uicheckbox(app.SETTINGSButtonGroup);
            app.MIDICheckBox_3.Text = 'MIDI';
            app.DAUCheckBox_3 = uicheckbox(app.SETTINGSButtonGroup);
            app.DAUCheckBox_3.Text = 'DAU';
            app.BloodOxygentionCheckBox_3 = uicheckbox(app.SETTINGSButtonGroup);
            app.BloodOxygentionCheckBox_3.Text = 'Blood Oxygention';
            app.PianoCheckBox_3 = uicheckbox(app.SETTINGSButtonGroup);
            app.PianoCheckBox_3.Text = 'Piano';
            app.PLOTLabel = uilabel(app.SETTINGSButtonGroup);
            app.PLOTLabel.Text = 'PLOT';
            app.xEditField_2 = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.xLabel = uilabel(app.SETTINGSButtonGroup);
            app.xLabel.Text = '≤ x ≤';
            app.xEditField_3 = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.xEditField_4 = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.yLabel = uilabel(app.SETTINGSButtonGroup);
            app.yLabel.Text = '≤ y ≤';
            app.xEditField_5 = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.DARKMODECheckBox = uicheckbox(app.SETTINGSButtonGroup);
            app.DARKMODECheckBox.Text = 'DARK MODE';
            app.GridCheckBox = uicheckbox(app.SETTINGSButtonGroup);
            app.GridCheckBox.Text = 'Grid';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = piano

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end